classdef AM_FM_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        FsFcFdFcLabel                   matlab.ui.control.Label
        ModulationparametersLabel       matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        PlaysoundCheckBox               matlab.ui.control.CheckBox
        FrequencydeviationEditField     matlab.ui.control.NumericEditField
        FrequencydeviationEditFieldLabel  matlab.ui.control.Label
        SamplingrateEditField           matlab.ui.control.NumericEditField
        SamplingrateEditFieldLabel      matlab.ui.control.Label
        CarrierfrequencyEditField       matlab.ui.control.NumericEditField
        CarrierfrequencyEditFieldLabel  matlab.ui.control.Label
        ModulationTypeDropDown          matlab.ui.control.DropDown
        ModulationTypeDropDownLabel     matlab.ui.control.Label
        AudiosourceDropDown             matlab.ui.control.DropDown
        AudiosourceDropDownLabel        matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        play_sound % Description
        fd
        fs
        fc
        modulation_type
        audio_source
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PlaysoundCheckBox
        function PlaysoundCheckBoxValueChanged(app, event)
            app.play_sound = app.PlaysoundCheckBox.Value;
            
        end

        % Value changed function: FrequencydeviationEditField
        function FrequencydeviationEditFieldValueChanged(app, event)
            app.fd = app.FrequencydeviationEditField.Value;
            
        end

        % Value changed function: SamplingrateEditField
        function SamplingrateEditFieldValueChanged(app, event)
            app.fs = app.SamplingrateEditField.Value;
            
        end

        % Value changed function: CarrierfrequencyEditField
        function CarrierfrequencyEditFieldValueChanged(app, event)
            app.fc = app.CarrierfrequencyEditField.Value;
            
        end

        % Value changed function: ModulationTypeDropDown
        function ModulationTypeDropDownValueChanged(app, event)
            app.modulation_type = app.ModulationTypeDropDown.Value;
            
        end

        % Value changed function: AudiosourceDropDown
        function AudiosourceDropDownValueChanged(app, event)
            app.audio_source = app.AudiosourceDropDown.Value;
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            AM_FM_Hilbert(app.audio_source, app.modulation_type, app.play_sound, app.fc, app.fs, app.fd, ...
                          app.UIAxes, app.UIAxes2, app.UIAxes3, app.UIAxes4, app.UIAxes5, app.UIAxes6)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {591, 591};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AudiosourceDropDownLabel = uilabel(app.LeftPanel);
            app.AudiosourceDropDownLabel.Text = 'Audio source';
            app.AudiosourceDropDown = uidropdown(app.LeftPanel);
            app.AudiosourceDropDown.Items = {'Recording', 'Melody'};
            app.AudiosourceDropDown.ValueChangedFcn = createCallbackFcn(app, @AudiosourceDropDownValueChanged, true);
            app.AudiosourceDropDown.Value = 'Recording';
            app.ModulationTypeDropDownLabel = uilabel(app.LeftPanel);
            app.ModulationTypeDropDownLabel.Text = 'Modulation Type';
            app.ModulationTypeDropDown = uidropdown(app.LeftPanel);
            app.ModulationTypeDropDown.Items = {'AM', 'FM'};
            app.ModulationTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModulationTypeDropDownValueChanged, true);
            app.ModulationTypeDropDown.Value = 'AM';
            app.CarrierfrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.CarrierfrequencyEditFieldLabel.Text = 'Carrier frequency';
            app.CarrierfrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CarrierfrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @CarrierfrequencyEditFieldValueChanged, true);
            app.SamplingrateEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplingrateEditFieldLabel.Text = 'Sampling rate';
            app.SamplingrateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SamplingrateEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingrateEditFieldValueChanged, true);
            app.FrequencydeviationEditFieldLabel = uilabel(app.LeftPanel);
            app.FrequencydeviationEditFieldLabel.Text = 'Frequency deviation';
            app.FrequencydeviationEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FrequencydeviationEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencydeviationEditFieldValueChanged, true);
            app.PlaysoundCheckBox = uicheckbox(app.LeftPanel);
            app.PlaysoundCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlaysoundCheckBoxValueChanged, true);
            app.PlaysoundCheckBox.Text = 'Play sound';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ModulationparametersLabel = uilabel(app.LeftPanel);
            app.ModulationparametersLabel.Text = 'Modulation parameters';
            app.FsFcFdFcLabel = uilabel(app.LeftPanel);
            app.FsFcFdFcLabel.Text = 'Fs >= Fc   |   Fd < Fc';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Original signal')
            xlabel(app.UIAxes, 'n [-]')
            ylabel(app.UIAxes, 'amplitude [-]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Step-by-step Hilbert function')
            xlabel(app.UIAxes2, 'n [-]')
            ylabel(app.UIAxes2, 'amplitude [-]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Matlab Hilbert function')
            xlabel(app.UIAxes3, 'n [-]')
            ylabel(app.UIAxes3, 'amplitude [-]')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.RightPanel);
            title(app.UIAxes4, 'Modulated signal')
            xlabel(app.UIAxes4, 'n [-]')
            ylabel(app.UIAxes4, 'amplitude [-]')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.RightPanel);
            title(app.UIAxes5, 'Demodulated signal - step-by-step Hilbert')
            xlabel(app.UIAxes5, 'n[-]')
            ylabel(app.UIAxes5, 'amplitude [-]')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.RightPanel);
            title(app.UIAxes6, 'Demodulated signal - matlab Hilbert')
            xlabel(app.UIAxes6, 'n [-]')
            ylabel(app.UIAxes6, 'amplitude [-]')
            zlabel(app.UIAxes6, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AM_FM_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end